classdef SFG_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DatasortingTab                 matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        InformationhelpButton          matlab.ui.control.Button
        AdditionalSettingsButton       matlab.ui.control.Button
        SamplesLabel                   matlab.ui.control.Label
        ViswavelengthcalibrationLabel  matlab.ui.control.Label
        ReferencesLabel                matlab.ui.control.Label
        calibUITable                   matlab.ui.control.Table
        sampleUITable                  matlab.ui.control.Table
        refUITable                     matlab.ui.control.Table
        ApplychangesButton             matlab.ui.control.Button
        LoadandsortdatafilesPanel      matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        SelectthefilesyouwanttoprocessLabel  matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        CurrentdirectoryLabel          matlab.ui.control.Label
        wdField                        matlab.ui.control.EditField
        SetchangeworkingdirectoryButton  matlab.ui.control.Button
        CalibrationstringEditField     matlab.ui.control.EditField
        CalibrationstringEditFieldLabel  matlab.ui.control.Label
        ReferencestringEditField       matlab.ui.control.EditField
        ReferencestringEditFieldLabel  matlab.ui.control.Label
        CalibrationTab                 matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        CalibratevisiblewavelengthLabel  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        CalibrationfileDropDown        matlab.ui.control.DropDown
        CalibrationfileDropDownLabel   matlab.ui.control.Label
        baselineSlider                 matlab.ui.control.Slider
        baselineSliderLabel            matlab.ui.control.Label
        multiplierSlider               matlab.ui.control.Slider
        multiplierSliderLabel          matlab.ui.control.Label
        xrangeSlider                   matlab.ui.control.RangeSlider
        xrangeSliderLabel              matlab.ui.control.Label
        rawcalibUIAxes                 matlab.ui.control.UIAxes
        processedcalibUIAxes           matlab.ui.control.UIAxes
        RawdataTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        SelectdatatoviewLabel_3        matlab.ui.control.Label
        Tree1                          matlab.ui.container.CheckBoxTree
        rawUIAxes                      matlab.ui.control.UIAxes
        AdditionalcleaningTab          matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        ApplychangesButton_2           matlab.ui.control.Button
        MannuallyinterpolatenotyetimplementedLabel  matlab.ui.control.Label
        AutomaticcleaningparametersLabel  matlab.ui.control.Label
        EditSelectedButton_2           matlab.ui.control.Button
        cleaningUITable_2              matlab.ui.control.Table
        EditSelectedButton             matlab.ui.control.Button
        cleaningUITable                matlab.ui.control.Table
        ProcesseddataTab               matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        SelectdatatoviewLabel_4        matlab.ui.control.Label
        Tree2                          matlab.ui.container.CheckBoxTree
        ExportAllButton                matlab.ui.control.Button
        ExportSelectedButton           matlab.ui.control.Button
        xrangeSlider_2                 matlab.ui.control.RangeSlider
        xrangeSlider_2Label            matlab.ui.control.Label
        processedUIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ref_str           string              % string of characters used to indicate a file corresponds to a reference (e.g. "quartz")
        calibration_str   string              % string of characters used to indicate a file corresponds to a calibration (e.g. "polystyrene")
        vis_wl            double              % wavelength of the visible beam
        center_wl         double = NaN        % center IR wavelength in nm (only if detected and equal for all files, else is NaN)

        data_dir                              % directory in which to look for files
        dirWatcher        timer               % Timer object for watching directory
        lastFileList      cell = {}           % Last known list of .csv files in data_dir

        sample_files      SampleFile          % array of SampleFile objects containing the sample files
        bg_files          File                % array of File objects containing the background files
        ref_files         SignalFile          % array of SignalFile objects containing the reference files
        calibration_files SampleFile          % array of SignalFile objects containing the calibration files

        polystyrene_std                       % NIST data for polystyrene imaginary refractive index

        bkendSampleTable  table               % sample/bg/ref files match for samples files, saved as table
        bkendRefTable     table               % signal/bg files match for reference files, saved as table
        bkendCalibTable   table               % sample/bg/ref files match for calibration files, saved as table

        settingsApp                           % instance of the settings app
        cleaningApp                           % instance of the cosmic ray cleaning app
    end

    properties (Access = public)
        bandwidth      double  = 1000         % bandwidth of the IR pulse in cm-1
        simon          logical = false        % use simon's secret options if true
    end

    % ==== File handling ====
    methods (Access = private)

        function processFiles(app)
            % process reference files
            for i = 1:height(app.bkendRefTable)
                signal = app.bkendRefTable.Signal(i);
                background = app.bkendRefTable.Background(i);

                signal.bg = background; % Assign background
            end

            % process sample files
            for i = 1:height(app.bkendSampleTable)
                sample = app.bkendSampleTable.Sample(i);
                background = app.bkendSampleTable.Background(i);
                reference = app.bkendSampleTable.Reference(i);

                % Assign the background and reference
                sample.bg = background;
                sample.ref = reference;

                % subtract bg, normalize, convert to wavenumber
                sample.process;
                sample.convert_to_wn(app.vis_wl);
            end

            % process calibration files
            for i = 1:height(app.bkendCalibTable)
                calib = app.bkendCalibTable.Calibration(i);
                background = app.bkendCalibTable.Background(i);
                reference = app.bkendCalibTable.Reference(i);

                % Assign the background and reference
                calib.bg = background;
                calib.ref = reference;

                % subtract bg, normalize, convert to wavenumber
                calib.process;
                calib.convert_to_absorbance; % convert from transmittance to absorbance
                calib.convert_to_wn(app.vis_wl);
            end

        end

        function bgFilename = matchBackground(app, signal)
            if isempty(signal.info)
                bgFilename = '';
                return;
            end

            sig_time = str2double(signal.info.time);

            best_diff = inf;
            bgFilename = [];

            for i = 1:numel(app.bg_files)
                f = app.bg_files(i);

                % Check for matching experimental conditions
                if strcmp(f.info.polarization, signal.info.polarization) && ...
                        f.info.region == signal.info.region && ...
                        strcmp(f.info.concentration, signal.info.concentration) && ...
                        strcmp(f.info.condition, signal.info.condition) && ...
                        strcmp(f.info.condition2, signal.info.condition2) && ...
                        strcmp(f.info.sample, signal.info.sample) && ...
                        f.info.acqtime == signal.info.acqtime

                    time = str2double(f.info.time);
                    dt = abs(time - sig_time);
                    if dt < best_diff
                        best_diff = dt;
                        bgFilename = char(f.filename);
                    end
                end
            end

            if isempty(bgFilename)
                bgFilename = '';
            end
        end

        function refFilename = matchRef(app, sample)
            if isempty(sample.info)
                refFilename = '';
                return;
            end
            sig_time = str2double(sample.info.time);

            best_diff = inf;
            refFilename = [];

            for i = 1:numel(app.ref_files)
                f = app.ref_files(i);

                % Check for matching experimental conditions
                if strcmp(f.info.polarization, sample.info.polarization) && ...
                        f.info.region == sample.info.region

                    time = str2double(f.info.time);
                    dt = abs(time - sig_time);
                    if dt < best_diff
                        best_diff = dt;
                        refFilename = char(f.filename);
                    end
                end
            end

            if isempty(refFilename)
                refFilename = '';
            end
        end

        function files = selectedFiles(app, uitable)
            % Get selected rows
            selectedRows = uitable.Selection;
            if isempty(selectedRows)
                files = {};
                return;
            end

            % Get filenames from the first column of selected rows
            selectedFilenames = cell(numel(selectedRows), 1);
            for i = 1:numel(selectedRows)
                rowIdx = selectedRows(1, i);
                selectedFilenames{i} = uitable.Data{rowIdx, 1};
            end

            % Initialize output
            files = cell(numel(selectedRows), 1);

            % Define file arrays to search
            fileArrays = {app.sample_files, app.bg_files, app.ref_files, app.calibration_files};

            % For each selected filename, search all file arrays
            for i = 1:numel(selectedFilenames)
                fname = string(selectedFilenames{i});
                found = false;
                for j = 1:numel(fileArrays)
                    thisArray = fileArrays{j};
                    for k = 1:numel(thisArray)
                        fileObj = thisArray(k);
                        if strcmp(fileObj.filename, fname)
                            files{i} = fileObj;
                            found = true;
                            break; % Stop once we find the first match
                        end
                    end
                    if found
                        break;
                    end
                end
            end
        end

        function export(app, sampleFiles)
            % Ask user for export directory
            export_dir = uigetdir(string(app.data_dir), "Select Export Directory");
            if isequal(export_dir, 0)
                return; % user canceled
            end

            for f = sampleFiles
                try
                    % Extract Wavenumber and Intensity columns
                    data = f.processed_data(:, {'Wavenumber', 'Intensity'});

                    % Build CSV file name
                    [~, name] = fileparts(f.filename);
                    csv_name = fullfile(export_dir, string(name) + "_processed.csv");

                    % Open file for writing
                    fid = fopen(csv_name, "w");
                    if fid == -1
                        error("Could not open file: %s", csv_name);
                    end

                    % ---- Write metadata header ----
                    fprintf(fid, "# Processed data export\n");
                    fprintf(fid, "# visible wavelength used : %g nm\n", app.vis_wl);
                    fprintf(fid, "# Background file used: %s\n", f.bg.filename);
                    fprintf(fid, "# Reference file used: %s\n", f.bg.filename);
                    fprintf(fid, "# Background for reference file: %s\n", f.bg.filename);
                    fprintf(fid, "# Date processed: %s\n", datetime("now", Format="yyyy-MM-dd HH:mm"));
                    fprintf(fid, "# -----------------------\n");

                    % ---- Write table header ----
                    fprintf(fid, "Wavenumber,Intensity\n");

                    % ---- Write table data ----
                    pairs = [data{:, 'Wavenumber'}.'; data{:, 'Intensity'}.'];
                    fprintf(fid, "%.6f,%.6f\n", pairs);

                    % Close file
                    fclose(fid);

                catch errorobj
                    report = getReport(errorobj);
                    uialert(app.UIFigure, report, "Error", Interpreter="html")
                    return
                end
            end

            uialert(app.UIFigure, "Exported succesfully", "Export successful", Icon="success")

        end

    end

    % ==== Utilities ====
    methods (Access = private)

        function loadState(app)
            % load data from StoredValues.mat into the app
            load("supporting files/StoredValues.mat", "state")
            app.vis_wl = state.vis_wl.Value;
            app.ref_str = state.ref_str.Value;
            app.calibration_str = state.calibration_str.Value;
            app.data_dir = state.data_dir;

        end

        function saveState(app)
            % save data from the app to StoredValues.mat
            state.data_dir = app.data_dir;
            state.vis_wl.Value = app.vis_wl;
            state.ref_str.Value = app.ref_str;
            state.calibration_str.Value = app.calibration_str;
            save("supporting files/StoredValues.mat", "state")
        end

    end

    % ==== Backend processes ====
    methods (Access = private)

        function sortFilesIncremental(app)
            % Process only checked nodes
            checkedNodes = app.Tree.CheckedNodes;

            % Track which files are currently in arrays for fast lookup
            currentSampleNames = lower(string({app.sample_files.filename}));
            currentCalibrationNames = lower(string({app.calibration_files.filename}));
            currentRefNames = lower(string({app.ref_files.filename}));
            currentBgNames = lower(string({app.bg_files.filename}));

            for i = 1:numel(checkedNodes)
                entry = checkedNodes(i).NodeData;

                % Skip invalid nodes or deleted files
                if ~isstruct(entry) || ~isfield(entry, "name") || ~isfield(entry, "folder")
                    continue
                end
                filepath = fullfile(entry.folder, entry.name);
                if exist(filepath, 'file') ~= 2
                    continue
                end

                fname = string(lower(entry.name));

                % Determine target array
                if ~contains(fname, ["bg","bkg","background"])
                    if contains(fname, app.calibration_str)
                        if ~any(currentCalibrationNames == fname)
                            f = SampleFile(entry);
                            app.calibration_files(end+1) = f;
                            currentCalibrationNames(end+1) = fname;
                        end
                    elseif ~contains(fname, app.ref_str)
                        if ~any(currentSampleNames == fname)
                            f = SampleFile(entry);
                            app.sample_files(end+1) = f;
                            currentSampleNames(end+1) = fname;
                        end
                    else
                        if ~any(currentRefNames == fname)
                            f = SignalFile(entry);
                            app.ref_files(end+1) = f;
                            currentRefNames(end+1) = fname;
                        end
                    end
                else
                    if ~any(currentBgNames == fname)
                        f = File(entry);
                        app.bg_files(end+1) = f;
                        currentBgNames(end+1) = fname;
                    end
                end
            end

            %% --- Remove unchecked or missing files from arrays ---
            % Keep only nodes whose NodeData is a struct with a 'name' field
            validNodes = checkedNodes(arrayfun(@(n) isstruct(n.NodeData) && isfield(n.NodeData, 'name'), checkedNodes));

            % Only keep files that exist on disk
            existingFiles = string(arrayfun(@(n) lower(n.NodeData.name), validNodes, 'UniformOutput', false));

            app.sample_files(~ismember(lower(string({app.sample_files.filename})), existingFiles)) = [];
            app.calibration_files(~ismember(lower(string({app.calibration_files.filename})), existingFiles)) = [];
            app.ref_files(~ismember(lower(string({app.ref_files.filename})), existingFiles)) = [];
            app.bg_files(~ismember(lower(string({app.bg_files.filename})), existingFiles)) = [];

            % Optional: check region for all files
            try
                app.checkRegion;
            catch
            end

            app.updateTables;
        end

        function sortFiles(app)
            % this method extract data from the files chosen (checked) via
            % app.Tree and based on the values for ref and calibration, it
            % sorts those files info backgrounds, references, samples, and
            % calibration.
            % For more info about data extraction, see the File classes used throughout this app.

            % only include selected (checked) files
            selected_files = struct( ...
                "name", {}, ...
                "folder", {}, ...
                "date", {}, ...
                "bytes", {}, ...
                "isdir", {}, ...
                "datenum", {} ...
                );

            for i = 1:numel(app.Tree.CheckedNodes)
                entry = app.Tree.CheckedNodes(i).NodeData;
                % Only include if it's a dir-like struct (not a group node)
                if isstruct(entry) && isfield(entry, "name") && isfield(entry, "folder")
                    selected_files(end+1) = entry;
                end
            end

            % === sort files and initialize File objects ===
            % app.sample_files = SampleFile.empty;
            % app.bg_files = File.empty;
            % app.ref_files = SignalFile.empty;
            % app.calibration_files = SampleFile.empty;

            % iterate through the selected files and sort them
            for i = 1:numel(selected_files)
                fname = lower(selected_files(i).name);

                % sort based on file name
                is_calibration = contains(fname, app.calibration_str);
                is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
                is_sig = ~contains(fname, "bg") && ~contains(fname, "bkg") && ~contains(fname, "background");

                % append to the correct array and convert into the
                % corresponding File object (see the different classes in
                % supporting files)
                if is_sig
                    if is_sample
                        f = SampleFile(selected_files(i));
                        app.sample_files(end+1) = f;
                    elseif is_calibration
                        f = SampleFile(selected_files(i));
                        app.calibration_files(end+1) = f;
                    else
                        f = SignalFile(selected_files(i));
                        app.ref_files(end+1) = f;
                    end
                else
                    f = File(selected_files(i));
                    app.bg_files(end+1) = f;
                end

                % if possible check the region (IR wavelength) corresponding to each file
                try
                    app.checkRegion;
                catch
                end
            end

        end

        % function readSampleTable(app)
        %     % read app.sampleUITable (data sorting tab) to correctly
        %     % attribute the corresponding reference and background for each
        %     % sample file, the result is stored in a backend table
        % 
        %     % Initialize cell arrays to hold matched objects
        %     nRows = height(app.sampleUITable.Data);
        %     samples     = cell(nRows, 1);
        %     backgrounds = cell(nRows, 1);
        %     references  = cell(nRows, 1);
        % 
        %     % Loop over each row of the UI table
        %     for i = 1:nRows
        %         % --- Extract char filenames and cast to string ---
        %         sampleName     = string(app.sampleUITable.Data{i, 1});
        %         backgroundName = string(app.sampleUITable.Data{i, 2});
        %         referenceName  = string(app.sampleUITable.Data{i, 3});
        % 
        %         % --- Match against object arrays ---
        %         % Find the SampleFile object
        %         samples{i} = app.sample_files(find([app.sample_files.filename] == sampleName, 1));
        % 
        %         % Find the Background File object
        %         backgrounds{i} = app.bg_files(find([app.bg_files.filename] == backgroundName, 1));
        % 
        %         % Find the SignalFile object
        %         references{i} = app.ref_files(find([app.ref_files.filename] == referenceName, 1));
        %     end
        % 
        %     % Create backend table with objects
        %     app.bkendSampleTable = table( ...
        %         [samples{:}]', ...
        %         [backgrounds{:}]', ...
        %         [references{:}]', ...
        %         VariableNames=["Sample", "Background", "Reference"] ...
        %         );
        % 
        % end
        % 
        % function readCalibTable(app)
        %     % read app.calibUITable (data sorting tab) to correctly
        %     % attribute the corresponding reference and background for each
        %     % calibtration file, the result is stored in a backend table
        % 
        %     % Initialize cell arrays to hold matched objects
        %     nRows = height(app.calibUITable.Data);
        %     samples     = cell(nRows, 1);
        %     backgrounds = cell(nRows, 1);
        %     references  = cell(nRows, 1);
        % 
        %     % Loop over each row of the UI table
        %     for i = 1:nRows
        %         % --- Extract char filenames and cast to string ---
        %         sampleName    = string(app.calibUITable.Data{i, 1});
        %         backgroundName = string(app.calibUITable.Data{i, 2});
        %         referenceName  = string(app.calibUITable.Data{i, 3});
        % 
        %         % --- Match against object arrays ---
        %         % Find the SampleFile object
        %         samples{i} = app.calibration_files( ...
        %             find([app.calibration_files.filename] == sampleName, 1) ...
        %             );
        % 
        %         % Find the Background File object
        %         backgrounds{i} = app.bg_files( ...
        %             find([app.bg_files.filename] == backgroundName, 1) ...
        %             );
        % 
        %         % Find the SignalFile object
        %         references{i} = app.ref_files( ...
        %             find([app.ref_files.filename] == referenceName, 1) ...
        %             );
        %     end
        % 
        %     % Create backend table with objects
        %     app.bkendCalibTable = table( ...
        %         [samples{:}]', ...
        %         [backgrounds{:}]', ...
        %         [references{:}]', ...
        %         VariableNames=["Calibration", "Background", "Reference"] ...
        %         );
        % 
        % end
        % 
        % function readRefTable(app)
        %     % Initialize cell arrays to hold matched objects
        %     nRows = height(app.refUITable.Data);
        %     signals     = cell(nRows, 1);
        %     backgrounds = cell(nRows, 1);
        % 
        %     % Loop over each row of the UI table
        %     for i = 1:nRows
        %         % --- Extract char filenames and cast to string ---
        %         signalName     = string(app.refUITable.Data{i, 1});
        %         backgroundName = string(app.refUITable.Data{i, 2});
        % 
        %         % --- Match against object arrays ---
        %         % Find the SignalFile object
        %         signals{i} = app.ref_files( ...
        %             find([app.ref_files.filename] == signalName, 1) ...
        %             );
        % 
        %         % Find the Background File object
        %         backgrounds{i} = app.bg_files( ...
        %             find([app.bg_files.filename] == backgroundName, 1) ...
        %             );
        %     end
        % 
        %     % Create backend table with objects
        %     app.bkendRefTable = table( ...
        %         [signals{:}]', ...
        %         [backgrounds{:}]', ...
        %         VariableNames= ["Signal", "Background"] ...
        %         );
        % 
        % end

        function readRefTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.refUITable.Data);
            signals     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);

            for i = 1:nRows
                signalName     = string(app.refUITable.Data{i, 1});
                backgroundName = string(app.refUITable.Data{i, 2});

                % Signal
                sigIdx = find([app.ref_files.filename] == signalName, 1);
                if isempty(sigIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', signalName), ...
                        'Reference Table Error');
                    return
                end
                signals{i} = app.ref_files(sigIdx);

                % Background
                bgIdx = find([app.bg_files.filename] == backgroundName, 1);
                if isempty(bgIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', backgroundName), ...
                        'Reference Table Error');
                    return
                end
                backgrounds{i} = app.bg_files(bgIdx);
            end

            app.bkendRefTable = table( ...
                [signals{:}]', ...
                [backgrounds{:}]', ...
                VariableNames=["Signal", "Background"] ...
                );
        end

        function readSampleTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.sampleUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);

            for i = 1:nRows
                sampleName     = string(app.sampleUITable.Data{i, 1});
                backgroundName = string(app.sampleUITable.Data{i, 2});
                referenceName  = string(app.sampleUITable.Data{i, 3});

                % Sample
                sampIdx = find([app.sample_files.filename] == sampleName, 1);
                if isempty(sampIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', sampleName), ...
                        'Sample Table Error');
                    return
                end
                samples{i} = app.sample_files(sampIdx);

                % Background
                bgIdx = find([app.bg_files.filename] == backgroundName, 1);
                if isempty(bgIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', backgroundName), ...
                        'Sample Table Error');
                    return
                end
                backgrounds{i} = app.bg_files(bgIdx);

                % Reference
                refIdx = find([app.ref_files.filename] == referenceName, 1);
                if isempty(refIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', referenceName), ...
                        'Sample Table Error');
                    return
                end
                references{i} = app.ref_files(refIdx);
            end

            app.bkendSampleTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                VariableNames=["Sample", "Background", "Reference"] ...
                );
        end

        function readCalibTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.calibUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);

            for i = 1:nRows
                sampleName    = string(app.calibUITable.Data{i, 1});
                backgroundName = string(app.calibUITable.Data{i, 2});
                referenceName  = string(app.calibUITable.Data{i, 3});

                % Calibration sample
                sampIdx = find([app.calibration_files.filename] == sampleName, 1);
                if isempty(sampIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', sampleName), ...
                        'Calibration Table Error');
                    return
                end
                samples{i} = app.calibration_files(sampIdx);

                % Background
                bgIdx = find([app.bg_files.filename] == backgroundName, 1);
                if isempty(bgIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', backgroundName), ...
                        'Calibration Table Error');
                    return
                end
                backgrounds{i} = app.bg_files(bgIdx);

                % Reference
                refIdx = find([app.ref_files.filename] == referenceName, 1);
                if isempty(refIdx)
                    uialert(app.UIFigure, ...
                        sprintf('Could not find file "%s". Double check that the spelling is correct and that the file exists.', referenceName), ...
                        'Calibration Table Error');
                    return
                end
                references{i} = app.ref_files(refIdx);
            end

            app.bkendCalibTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                VariableNames=["Calibration", "Background", "Reference"] ...
                );
        end

        function checkRegion(app)
            % Helper function to extract valid region values from a file array
            function regions = extractRegions(fileArray)
                regions = [];
                for i = 1:numel(fileArray)
                    file = fileArray(i);
                    if isprop(file, "info") && isfield(file.info, "region") && ...
                            isnumeric(file.info.region) && isscalar(file.info.region)
                        regions(end+1) = file.info.region; %#ok<AGROW>
                    else
                        regions = NaN; % Use NaN to indicate invalid/missing region
                        return
                    end
                end
            end

            % Extract region values from each file type
            r1 = extractRegions(app.sample_files);
            r2 = extractRegions(app.bg_files);
            r3 = extractRegions(app.ref_files);
            r4 = extractRegions(app.calibration_files);

            % Combine all region values (if all are valid)
            if ~any(isnan([r1, r2, r3, r4]))
                all_regions = [r1, r2, r3, r4];
                if all(abs(all_regions - all_regions(1)) < 1e-6)
                    app.center_wl = all_regions(1);
                end
            end
        end

        function startDirectoryWatcher(app)
            % Stop existing watcher if running
            if ~isempty(app.dirWatcher) && isvalid(app.dirWatcher)
                stop(app.dirWatcher);
                delete(app.dirWatcher);
            end

            % Store initial file list
            app.lastFileList = {dir(fullfile(app.data_dir, '*.csv')).name};

            % Create timer to check folder every 2 seconds
            app.dirWatcher = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 2, ... % seconds
                'TimerFcn', @(~,~) app.updateFilesTree ...
                );

            start(app.dirWatcher);
        end

    end

    % ==== UI management (private) ====
    methods (Access = private)

        function updateFilesTree(app)
            % Stop if app is gone
            if ~isvalid(app) || ~isvalid(app.Tree)
                return;
            end

            % Get current CSV file list
            currentFiles = dir(fullfile(app.data_dir, '*.csv'));
            currentNames = {currentFiles.name};

            %% If the tree is empty → full rebuild
            if isempty(app.Tree.Children)
                % Create parent node
                all_node = uitreenode(app.Tree, "Text", "All Files", "NodeData", []);
                % Add children
                for i = 1:numel(currentFiles)
                    uitreenode(all_node, Text=currentFiles(i).name, NodeData=currentFiles(i));
                end
                % Select all by default
                app.Tree.CheckedNodes = all_node;
                expand(app.Tree, "all");

                % Store list for next comparison
                app.lastFileList = currentNames;

                app.sortFiles;
                app.updateTables;
                app.buildCleaningTable;
                app.buildTree1;            % initialize the raw data tab tree
                
                % % initialize raw data tab widgets (slider)
                % x_vals = app.sample_files(1).raw_data.Wavelength;
                % app.raxrangeSlider.Limits = [min(x_vals), max(x_vals)];
                % app.raxrangeSlider.Value = [min(x_vals), max(x_vals)];
                return;
            end

            %% --- Incremental update ---
            % Get the "All Files" parent node
            all_node = app.Tree.Children(1);
            existingNames = arrayfun(@(n) n.Text, all_node.Children, 'UniformOutput', false);

            % --- Add new files ---
            filesToAdd = setdiff(currentNames, existingNames);
            for i = 1:numel(filesToAdd)
                idx = strcmp({currentFiles.name}, filesToAdd{i});
                uitreenode(all_node, Text=currentFiles(idx).name, NodeData=currentFiles(idx));
            end

            % --- Remove missing files ---
            filesToRemove = setdiff(existingNames, currentNames);
            for i = 1:numel(filesToRemove)
                % Find the node in the current children list
                nodeToRemove = [];
                for k = 1:numel(all_node.Children)
                    c = all_node.Children(k);
                    if strcmp(c.Text, filesToRemove{i})
                        nodeToRemove = c;
                        break;
                    end
                end
                if ~isempty(nodeToRemove) && isvalid(nodeToRemove)
                    delete(nodeToRemove);
                end
            end

            % Update stored list
            app.lastFileList = currentNames;

            if ~isempty(filesToRemove) || ~isempty(filesToAdd) % if things have been modifed
                app.sortFilesIncremental;
                app.buildCleaningTable;
                app.buildTree1;            % initialize the raw data tab tree
            end
        end

        function UpdatePcalibUIAxes(app)
            % when called, this method updates app.processedcalibUIAxes,
            % which is the axes on which the calibration absorbance and
            % NIST data is plotted.
            calibration = app.CalibrationfileDropDown.Value;
            calibration.convert_to_wn(app.vis_wl);

            multiplier = app.multiplierSlider.Value;
            baseline = app.baselineSlider.Value;
            app.polystyrene_std.adj_abs = ...
                app.polystyrene_std.ImaginaryRefractiveIndex .* multiplier + baseline;

            cla(app.processedcalibUIAxes)
            hold(app.processedcalibUIAxes, "on");
            plot(app.processedcalibUIAxes, app.polystyrene_std, "Wavenumber", "adj_abs");
            plot(app.processedcalibUIAxes, calibration.processed_data, "Wavenumber", "Absorbance");
            hold(app.processedcalibUIAxes, "off");

            app.processedcalibUIAxes.XLim = app.xrangeSlider.Value;
            app.processedcalibUIAxes.XLabel.String = "Wavenumber (cm^{-1})";
            app.processedcalibUIAxes.YLabel.String = "Absorbance (a.u.)";
            legend(app.processedcalibUIAxes, "NIST", "Collected", Location="best");
        end

        function plotRaw(app)
            % plot raw data for all checked nodes
            nodes = app.Tree1.CheckedNodes;

            cla(app.rawUIAxes)
            hold(app.rawUIAxes, "on");
            for i = 1:numel(nodes)
                f = nodes(i).NodeData;
                if isa(f, "File")
                    if strcmp(nodes(i).Parent.Parent.NodeData, "clean")
                        f.cleanRays;
                        plot(app.rawUIAxes, ...
                            f.raw_data, "Wavelength", "CleanIntensity", ...
                            "DisplayName", f.filename + " (clean)" ...
                            )
                    else
                        plot(app.rawUIAxes, ...
                            f.raw_data, "Wavelength", "Intensity", ...
                            "DisplayName", f.filename ...
                            )
                    end
                end

            end
            hold(app.rawUIAxes, "off");

            app.rawUIAxes.XLabel.String = "Wavelength (nm)";
            app.rawUIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.rawUIAxes, Location="best", Interpreter="none");

        end

        function plotProcessed(app)
            % plot processed data for all checked nodes
            nodes = app.Tree2.CheckedNodes;

            cla(app.processedUIAxes)
            hold(app.processedUIAxes, "on");
            for i = 1:numel(nodes)
                f = nodes(i).NodeData;
                if isa(f, "File")
                    plot( ...
                        app.processedUIAxes, ...
                        f.processed_data, "Wavenumber", "Intensity", ...
                        "DisplayName", f.filename ...
                        )
                end
            end
            hold(app.processedUIAxes, "off");

            app.processedUIAxes.XLabel.String = "Wavenumber (cm^{-1})";
            app.processedUIAxes.YLabel.String = "Intensity (a.u.)";
            legend(app.processedUIAxes, Location="best", Interpreter="none");

            app.processedUIAxes.XLim = app.xrangeSlider_2.Value;

        end

        function plotCalibration(app, calib)
            ref = calib.ref;
            bg = calib.bg;

            cla(app.rawcalibUIAxes, "reset")

            hold(app.rawcalibUIAxes, "on");
            plot( ...
                app.rawcalibUIAxes, ...
                bg.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", bg.filename ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                ref.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", ref.filename ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                calib.raw_data, "Wavelength", "Intensity", ...
                "DisplayName", calib.filename ...
                )
            hold(app.rawcalibUIAxes, "off");

            app.rawcalibUIAxes.XLabel.String = "Wavelength (nm)";
            app.rawcalibUIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.rawcalibUIAxes, "Location", "best", "Interpreter", "none");

            x_vals = calib.processed_data.Wavenumber;
            app.xrangeSlider.Limits = [min(x_vals), max(x_vals)];
            if isfield(calib.info, "region") && isnumeric(calib.info.region) && isscalar(calib.info.region)
                um = calib.info.region;
                min_x = 1e4 / um - app.bandwidth / 2;
                max_x = 1e4 / um + app.bandwidth / 2;
                app.xrangeSlider.Value = [min_x, max_x];
            else
                app.xrangeSlider.Value = [min(x_vals), max(x_vals)];
            end

            cla(app.processedcalibUIAxes, "reset")

            app.UpdatePcalibUIAxes();

        end

        function buildTree1(app)
            % prepare all tree nodes and subnodes
            delete(app.Tree1.Children); % clean up old nodes

            % build parent nodes
            raw_node = uitreenode(app.Tree1, Text="Raw data", NodeData=[]);
            clean_node = uitreenode(app.Tree1, Text="Cleaned data", NodeData="clean");

            sample_node = uitreenode(raw_node, Text="Samples", NodeData=[]);
            ref_node = uitreenode(raw_node, Text="References", NodeData=[]);
            bg_node = uitreenode(raw_node, Text="Backgrounds", NodeData=[]);
            clean_sample_node = uitreenode(clean_node, Text="Samples", NodeData=[]);
            clean_ref_node = uitreenode(clean_node, Text="References", NodeData=[]);
            clean_bg_node = uitreenode(clean_node, Text="Backgrounds", NodeData=[]);

            % build children nodes for each file
            for i = 1:numel(app.sample_files)
                uitreenode(sample_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
                uitreenode(clean_sample_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
            end

            for i = 1:numel(app.ref_files)
                uitreenode(ref_node, Text=app.ref_files(i).filename, NodeData=app.ref_files(i));
                uitreenode(clean_ref_node, Text=app.ref_files(i).filename, NodeData=app.ref_files(i));
            end

            for i = 1:numel(app.bg_files)
                uitreenode(bg_node, Text=app.bg_files(i).filename, NodeData=app.bg_files(i));
                uitreenode(clean_bg_node, Text=app.bg_files(i).filename, NodeData=app.bg_files(i));
            end

            % select some nodes by default and expand the tree
            app.Tree1.CheckedNodes = clean_node;
            expand(app.Tree1);
        end

        function updateTables(app)
            % --- Prepare background and reference dropdowns ---
            bgs  = makeDropdown(app.bg_files, 'empty');
            refs = makeDropdown(app.ref_files, 'empty');

            % --- Update each table ---
            updateTable(app.refUITable, app.ref_files, {[], bgs}, ...
                @(f) {char(f.filename), app.matchBackground(f)});

            updateTable(app.sampleUITable, app.sample_files, {[], bgs, refs}, ...
                @(f) {char(f.filename), app.matchBackground(f), app.matchRef(f)});

            updateTable(app.calibUITable, app.calibration_files, {[], bgs, refs}, ...
                @(f) {char(f.filename), app.matchBackground(f), app.matchRef(f)});

            % --- Keep tables editable ---
            app.refUITable.ColumnEditable     = true;
            app.sampleUITable.ColumnEditable  = true;
            app.calibUITable.ColumnEditable   = true;


            %% === Nested helper functions ===

            % Create dropdown options from files
            function list = makeDropdown(files, emptyValue)
                if isempty(files) || isscalar(files)
                    list = {emptyValue}; % must be cell of char
                else
                    % Force filenames to char inside a cell array
                    list = cellfun(@char, {files.filename}, 'UniformOutput', false);
                end
            end


            % Update a specific table
            function updateTable(tableHandle, fileObjs, colFormats, rowFunc)
                currentFiles = cellstr({fileObjs.filename});
                data = tableHandle.Data;

                % Remove deleted files (only if table has rows)
                if ~isempty(data)
                    toKeep = ismember(data(:,1), currentFiles);
                    data = data(toKeep, :);
                end

                % Add new files (check by filename)
                for fileObj = fileObjs
                    if isempty(data) || ~ismember(fileObj.filename, data(:,1))
                        data = [data; rowFunc(fileObj)];
                    end
                end

                % Assign updated data & formats
                tableHandle.Data = data;
                tableHandle.ColumnFormat = colFormats;
            end
        end

        function buildTree2(app)
            % prepare all tree nodes and subnodes
            delete(app.Tree2.Children);
            all_node = uitreenode(app.Tree2, Text="All Files", NodeData=[]);

            for i = 1:numel(app.sample_files)
                uitreenode(all_node, Text=app.sample_files(i).filename, NodeData=app.sample_files(i));
            end

            % select all nodes by default and expand the tree
            app.Tree2.CheckedNodes = all_node;
            expand(app.Tree2, "all");
        end

        function buildCleaningTable(app)
            tableData = {};  % Initialize empty cell array

            % Helper to extract info from any file array
            function extractData(fileArray)
                for i = 1:numel(fileArray)
                    f = fileArray(i);

                    % Extract properties
                    tableData(end+1, :) = { ...
                        char(f.filename), ...
                        f.tf, ...
                        f.window ...
                        };
                end
            end

            % Process each file group
            extractData(app.sample_files);
            extractData(app.bg_files);
            extractData(app.ref_files);
            extractData(app.calibration_files);

            % Update cleaningUITable
            app.cleaningUITable.Data = tableData;
            app.cleaningUITable.ColumnName = {"Filename", "Threshold Factor", "Window"};
        end

    end

    % ==== UI management (public) ====
    methods (Access = public)

        function enableSettingsButton(app)
            % Re-enable the settings button
            app.AdditionalSettingsButton.Enable = "on";
        end

        function updateCleaningTable(app)
            app.buildCleaningTable;

            % Re-enable the settings button
            app.EditSelectedButton.Enable = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, "center");

            app.polystyrene_std = readtable("polystyrene_imaginary_refractive_index.csv"); % load NIST polystyrene data

            % load starting parameters
            try
                app.loadState;
                app.wdField.Value = string(app.data_dir);
            catch
                app.vis_wl = 793;
                app.ref_str = "zqz";
                app.calibration_str = "polystyrene";
                app.wdField.Value = "None";
            end

            % initialize vis calibration widgets
            app.Spinner.Value = app.vis_wl;

            app.ProcesseddataTab.Parent = []; % hide processed data tab until data is processed
            app.sampleUITableCellEdit;        % makes sure button is disabled until tables are filled

            try
                app.updateFilesTree;
            catch
                app.data_dir = uigetdir();
                if isequal(app.data_dir, 0)
                    return; % user canceled
                end
                app.wdField.Value = string(app.data_dir);
                app.updateFilesTree;
            end

            app.startDirectoryWatcher; % Start watching for changes

        end

        % Button pushed function: SetchangeworkingdirectoryButton
        function SetchangeworkingdirectoryButtonPushed(app, event)
            try
                app.data_dir = uigetdir(app.data_dir);
            catch
                app.data_dir = uigetdir();
            end
            if isequal(app.data_dir, 0)
                return; % user canceled
            end

            app.wdField.Value = string(app.data_dir);

            % Initial build from scratch
            app.updateFilesTree;
            % app.sortFiles;      % Full sort the first time
            % app.updateTables;    % Full table build the first time
            app.startDirectoryWatcher; % Start watching for changes

        end

        % Value changed function: ReferencestringEditField
        function ReferencestringEditFieldValueChanged(app, event)
            app.ref_str = string(app.ReferencestringEditField.Value);
            app.sortFiles;
            app.updateTables;

        end

        % Value changed function: CalibrationstringEditField
        function CalibrationstringEditFieldValueChanged(app, event)
            app.calibration_str = string(app.CalibrationstringEditField.Value);
            app.sortFiles;
            app.updateTables;

        end

        % Callback function: not associated with a component
        function SortfilesButtonPushed(app, event)
            %% button does not exist anymore

            % % update storing strings
            % app.calibration_str = string(app.CalibrationstringEditField.Value);
            % app.ref_str = string(app.ReferencestringEditField.Value);

            % app.sortFiles;     % sort files into bg, ref, sample, and calibration
            % 
            % % make sure every file needed is present
            % files = {app.bg_files, app.sample_files, app.ref_files, app.calibration_files};
            % names = {"Background files", "Sample files", "Reference files", "Calibration files"};
            % 
            % for i = 1:4
            %     if isempty(files{i})
            %         uialert(app.UIFigure, sprintf("%s are missing.", names{i}), "Error");
            %         return;
            %     end
            % end


            % app.buildTree1;            % initialize the raw data tab tree
            % app.updateTables;           % initialize the data sorting tab tables
            % app.sampleUITableCellEdit; % makes sure button is disabled until tables are filled
            % 
            % % initialize raw data tab widgets (slider)
            % x_vals = app.sample_files(1).raw_data.Wavelength;
            % app.raxrangeSlider.Limits = [min(x_vals), max(x_vals)];
            % app.raxrangeSlider.Value = [min(x_vals), max(x_vals)];

            % % initialize cleaning tables
            % app.buildCleaningTable;

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.vis_wl = app.Spinner.Value;
            app.UpdatePcalibUIAxes();

        end

        % Value changed function: baselineSlider, multiplierSlider, 
        % ...and 1 other component
        function baselineSliderValueChanged(app, event)
            app.UpdatePcalibUIAxes();

        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            % read the tables, initialize wavelength calibration, process data
            app.readRefTable;
            app.readCalibTable;
            app.readSampleTable;

            app.processFiles;
            app.buildTree2;
            app.ProcesseddataTab.Parent = app.TabGroup; % Make the tab visible

            % Prepare dropdown for calibration
            app.CalibrationfileDropDown.Items = [app.calibration_files.filename];
            app.CalibrationfileDropDown.ItemsData = app.calibration_files;

            % prepare the sliders
            x_vals = app.sample_files(1).processed_data.Wavenumber;
            app.xrangeSlider_2.Limits = [min(x_vals), max(x_vals)];
            if ~isnan(app.center_wl)
                um = app.sample_files(1).info.region;
                min_x = 1e4 / um - app.bandwidth / 2;
                max_x = 1e4 / um + app.bandwidth / 2;
                app.xrangeSlider_2.Value = [min_x, max_x];
            else
                app.xrangeSlider_2.Value = [min(x_vals), max(x_vals)];
            end

            app.plotCalibration(app.calibration_files(1));

        end

        % Callback function: RawdataTab, Tree1
        function RawdataTabButtonDown(app, event)
            app.plotRaw;

        end

        % Callback function: ProcesseddataTab, Tree2, xrangeSlider_2
        function ProcesseddataTabButtonDown(app, event)
            app.plotProcessed;
        end

        % Value changed function: CalibrationfileDropDown
        function CalibrationfileDropDownValueChanged(app, event)
            value = app.CalibrationfileDropDown.Value;
            app.plotCalibration(value);

        end

        % Cell edit callback: calibUITable, refUITable, sampleUITable
        function sampleUITableCellEdit(app, event)
            % Check each table individually
            tables = {app.calibUITable, app.refUITable, app.sampleUITable};
            allFilled = true;

            for i = 1:numel(tables)
                data = tables{i}.Data;

                % Skip if data is empty or contains empty/invalid strings
                if isempty(data) || any(cellfun(@(c) isempty(c) || isequal(c, '') || isequal(c, 'empty'), data(:)))
                    allFilled = false;
                    break;
                end
            end

            % Enable or disable button
            app.ApplychangesButton.Enable = matlab.lang.OnOffSwitchState(allFilled);

        end

        % Button pushed function: InformationhelpButton
        function InformationhelpButtonPushed(app, event)
            web("https://github.com/silanglois/Process-SFG-data", "-browser");
            message = [
                "Opening in your browser. If it doesn't appear, please check your taskbar.<br>" ...
                "You can also click <a href=""https://github.com/silanglois/Process-SFG-data"">this link</a> to open it manually." ...
                ];
            uialert(app.UIFigure, message, "Information", Icon="info", Interpreter="html");

        end

        % Button pushed function: AdditionalSettingsButton
        function AdditionalSettingsButtonPushed(app, event)
            app.AdditionalSettingsButton.Enable = "off";
            app.settingsApp = settings_app(app, app.bandwidth, app.simon);          % Create the settings dialog

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveState;
            delete(app.settingsApp)
            delete(app.cleaningApp)
            delete(app)

        end

        % Button pushed function: EditSelectedButton
        function EditSelectedButtonPushed(app, event)
            files = app.selectedFiles(app.cleaningUITable);
            if isempty(files)
                message = "First select files to edit by clicking on the corresponding table row.";
                uialert(app.UIFigure, message, "Error", Icon="error")
            else
                app.EditSelectedButton.Enable = "off";
                app.cleaningApp = cleaning_app(app, files);
            end

        end

        % Button pushed function: ApplychangesButton_2
        function ApplychangesButton_2Pushed(app, event)
            app.processFiles;

        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            app.export(app.sample_files);

        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            nodes = app.Tree2.CheckedNodes;
            export_files = SampleFile.empty;
            for i = 1:numel(nodes)
                if isa(nodes(i).NodeData, "SampleFile")
                    export_files(end+1) = nodes(i).NodeData;
                end
            end
            app.export(export_files)
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.sortFilesIncremental;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatasortingTab = uitab(app.TabGroup);
            app.DatasortingTab.Title = 'Data sorting';
            app.GridLayout10 = uigridlayout(app.DatasortingTab);
            app.LoadandsortdatafilesPanel = uipanel(app.GridLayout10);
            app.LoadandsortdatafilesPanel.Title = 'Load and sort data files';
            app.GridLayout12 = uigridlayout(app.LoadandsortdatafilesPanel);
            app.ReferencestringEditFieldLabel = uilabel(app.GridLayout12);
            app.ReferencestringEditFieldLabel.Text = 'Reference string';
            app.ReferencestringEditField = uieditfield(app.GridLayout12, 'text');
            app.ReferencestringEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencestringEditFieldValueChanged, true);
            app.ReferencestringEditField.Value = 'zqz';
            app.CalibrationstringEditFieldLabel = uilabel(app.GridLayout12);
            app.CalibrationstringEditFieldLabel.Text = 'Calibration string';
            app.CalibrationstringEditField = uieditfield(app.GridLayout12, 'text');
            app.CalibrationstringEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationstringEditFieldValueChanged, true);
            app.CalibrationstringEditField.Value = 'polystyrene';
            app.SetchangeworkingdirectoryButton = uibutton(app.GridLayout12, 'push');
            app.SetchangeworkingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetchangeworkingdirectoryButtonPushed, true);
            app.SetchangeworkingdirectoryButton.Text = 'Set/change working directory';
            app.wdField = uieditfield(app.GridLayout12, 'text');
            app.wdField.Editable = 'off';
            app.CurrentdirectoryLabel = uilabel(app.GridLayout12);
            app.CurrentdirectoryLabel.Text = 'Current directory: ';
            app.Tree = uitree(app.GridLayout12, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelectthefilesyouwanttoprocessLabel = uilabel(app.GridLayout12);
            app.SelectthefilesyouwanttoprocessLabel.Text = 'Select the files you want to process:';
            app.ApplychangesButton = uibutton(app.GridLayout10, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.refUITable = uitable(app.GridLayout10);
            app.refUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.refUITable.RowName = {};
            app.refUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.sampleUITable = uitable(app.GridLayout10);
            app.sampleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.sampleUITable.RowName = {};
            app.sampleUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.calibUITable = uitable(app.GridLayout10);
            app.calibUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.calibUITable.RowName = {};
            app.calibUITable.CellEditCallback = createCallbackFcn(app, @sampleUITableCellEdit, true);
            app.ReferencesLabel = uilabel(app.GridLayout10);
            app.ReferencesLabel.Text = 'References';
            app.ViswavelengthcalibrationLabel = uilabel(app.GridLayout10);
            app.ViswavelengthcalibrationLabel.Text = 'Vis wavelength calibration';
            app.SamplesLabel = uilabel(app.GridLayout10);
            app.SamplesLabel.Text = 'Samples';
            app.AdditionalSettingsButton = uibutton(app.GridLayout10, 'push');
            app.AdditionalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionalSettingsButtonPushed, true);
            app.AdditionalSettingsButton.Text = 'Additional Settings';
            app.InformationhelpButton = uibutton(app.GridLayout10, 'push');
            app.InformationhelpButton.ButtonPushedFcn = createCallbackFcn(app, @InformationhelpButtonPushed, true);
            app.InformationhelpButton.Text = 'Information/help';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout7 = uigridlayout(app.CalibrationTab);
            app.processedcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.processedcalibUIAxes, 'Processed')
            xlabel(app.processedcalibUIAxes, 'Frequency (cm-1)')
            ylabel(app.processedcalibUIAxes, 'Absorbance (a.u.)')
            zlabel(app.processedcalibUIAxes, 'Z')
            app.rawcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.rawcalibUIAxes, 'Raw')
            xlabel(app.rawcalibUIAxes, 'X')
            ylabel(app.rawcalibUIAxes, 'Y')
            zlabel(app.rawcalibUIAxes, 'Z')
            app.xrangeSliderLabel = uilabel(app.GridLayout7);
            app.xrangeSliderLabel.Text = 'x range';
            app.xrangeSlider = uislider(app.GridLayout7, 'range');
            app.xrangeSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSliderLabel = uilabel(app.GridLayout7);
            app.multiplierSliderLabel.Text = 'multiplier';
            app.multiplierSlider = uislider(app.GridLayout7);
            app.multiplierSlider.Limits = [0 20];
            app.multiplierSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSlider.Value = 10;
            app.baselineSliderLabel = uilabel(app.GridLayout7);
            app.baselineSliderLabel.Text = 'baseline';
            app.baselineSlider = uislider(app.GridLayout7);
            app.baselineSlider.Limits = [0 3];
            app.baselineSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.CalibrationfileDropDownLabel = uilabel(app.GridLayout7);
            app.CalibrationfileDropDownLabel.Text = 'Calibration file';
            app.CalibrationfileDropDown = uidropdown(app.GridLayout7);
            app.CalibrationfileDropDown.Items = {};
            app.CalibrationfileDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationfileDropDownValueChanged, true);
            app.CalibrationfileDropDown.Placeholder = 'First fill out the tables in the "Data sorting" tab.';
            app.CalibrationfileDropDown.Value = {};
            app.Spinner = uispinner(app.GridLayout7);
            app.Spinner.Step = 0.05;
            app.Spinner.ValueDisplayFormat = '%.2f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CalibratevisiblewavelengthLabel = uilabel(app.GridLayout7);
            app.CalibratevisiblewavelengthLabel.Text = 'Calibrate visible wavelength';
            app.RawdataTab = uitab(app.TabGroup);
            app.RawdataTab.Title = 'Raw data';
            app.RawdataTab.ButtonDownFcn = createCallbackFcn(app, @RawdataTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.RawdataTab);
            app.rawUIAxes = uiaxes(app.GridLayout3);
            xlabel(app.rawUIAxes, 'X')
            ylabel(app.rawUIAxes, 'Y')
            zlabel(app.rawUIAxes, 'Z')
            app.Tree1 = uitree(app.GridLayout3, 'checkbox');
            % Assign Checked Nodes
            app.Tree1.CheckedNodesChangedFcn = createCallbackFcn(app, @RawdataTabButtonDown, true);
            app.SelectdatatoviewLabel_3 = uilabel(app.GridLayout3);
            app.SelectdatatoviewLabel_3.Text = 'Select data to view:';
            app.AdditionalcleaningTab = uitab(app.TabGroup);
            app.AdditionalcleaningTab.Title = 'Additional cleaning';
            app.GridLayout15 = uigridlayout(app.AdditionalcleaningTab);
            app.cleaningUITable = uitable(app.GridLayout15);
            app.cleaningUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.cleaningUITable.RowName = {};
            app.cleaningUITable.SelectionType = 'row';
            app.EditSelectedButton = uibutton(app.GridLayout15, 'push');
            app.EditSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @EditSelectedButtonPushed, true);
            app.EditSelectedButton.Text = 'Edit Selected';
            app.cleaningUITable_2 = uitable(app.GridLayout15);
            app.cleaningUITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.cleaningUITable_2.RowName = {};
            app.cleaningUITable_2.SelectionType = 'row';
            app.EditSelectedButton_2 = uibutton(app.GridLayout15, 'push');
            app.EditSelectedButton_2.Text = 'Edit Selected';
            app.AutomaticcleaningparametersLabel = uilabel(app.GridLayout15);
            app.AutomaticcleaningparametersLabel.Text = 'Automatic cleaning parameters';
            app.MannuallyinterpolatenotyetimplementedLabel = uilabel(app.GridLayout15);
            app.MannuallyinterpolatenotyetimplementedLabel.Text = 'Mannually interpolate (not yet implemented)';
            app.ApplychangesButton_2 = uibutton(app.GridLayout15, 'push');
            app.ApplychangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButton_2Pushed, true);
            app.ApplychangesButton_2.Text = 'Apply changes';
            app.ProcesseddataTab = uitab(app.TabGroup);
            app.ProcesseddataTab.Title = 'Processed data';
            app.ProcesseddataTab.ButtonDownFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.GridLayout = uigridlayout(app.ProcesseddataTab);
            app.processedUIAxes = uiaxes(app.GridLayout);
            xlabel(app.processedUIAxes, 'X')
            ylabel(app.processedUIAxes, 'Y')
            zlabel(app.processedUIAxes, 'Z')
            app.xrangeSlider_2Label = uilabel(app.GridLayout);
            app.xrangeSlider_2Label.Text = 'x range';
            app.xrangeSlider_2 = uislider(app.GridLayout, 'range');
            app.xrangeSlider_2.ValueChangedFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.ExportSelectedButton = uibutton(app.GridLayout, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Text = 'Export Selected';
            app.ExportAllButton = uibutton(app.GridLayout, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout14 = uigridlayout(app.Panel);
            app.Tree2 = uitree(app.GridLayout14, 'checkbox');
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.SelectdatatoviewLabel_4 = uilabel(app.GridLayout14);
            app.SelectdatatoviewLabel_4.Text = 'Select data to view:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFG_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end