classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DatasortingTab                 matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        sampleUITable                  matlab.ui.control.Table
        refUITable                     matlab.ui.control.Table
        ApplychangesButton             matlab.ui.control.Button
        LoadandsortdatafilesPanel      matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        CurrentdirectoryLabel          matlab.ui.control.Label
        wdField                        matlab.ui.control.EditField
        SetchangeworkingdirectoryButton  matlab.ui.control.Button
        LoadfilesButton                matlab.ui.control.Button
        CalibrationstringEditField     matlab.ui.control.EditField
        CalibrationstringEditFieldLabel  matlab.ui.control.Label
        ReferencestringEditField       matlab.ui.control.EditField
        ReferencestringEditFieldLabel  matlab.ui.control.Label
        CalibrationTab                 matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        baselineSlider                 matlab.ui.control.Slider
        baselineSliderLabel            matlab.ui.control.Label
        multiplierSlider               matlab.ui.control.Slider
        multiplierSliderLabel          matlab.ui.control.Label
        xrangeSlider                   matlab.ui.control.RangeSlider
        xrangeSliderLabel              matlab.ui.control.Label
        CalibratevisiblewavelengthPanel  matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Spinner                        matlab.ui.control.Spinner
        ViswlSlider                    matlab.ui.control.Slider
        SetcalibrationfilesPanel       matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        UpdateplotsButton              matlab.ui.control.Button
        BackgroundusedDropDown         matlab.ui.control.DropDown
        BackgroundusedDropDownLabel    matlab.ui.control.Label
        ReferenceusedDropDown          matlab.ui.control.DropDown
        ReferenceusedLabel             matlab.ui.control.Label
        CalibrationdatafileDropDown    matlab.ui.control.DropDown
        CalibrationdatafileDropDownLabel  matlab.ui.control.Label
        processedcalibUIAxes           matlab.ui.control.UIAxes
        rawcalibUIAxes                 matlab.ui.control.UIAxes
        RawdataTab                     matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        AllTab                         matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        raxrangeSlider                 matlab.ui.control.RangeSlider
        xrangeLabel                    matlab.ui.control.Label
        Tree_3                         matlab.ui.container.CheckBoxTree
        Node_3                         matlab.ui.container.TreeNode
        Node2_3                        matlab.ui.container.TreeNode
        Node3_3                        matlab.ui.container.TreeNode
        Node4_3                        matlab.ui.container.TreeNode
        allrawUIAxes                   matlab.ui.control.UIAxes
        SampleTab                      matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        ListBox_4                      matlab.ui.control.ListBox
        rsxrangeSlider                 matlab.ui.control.RangeSlider
        xrangeLabel_2                  matlab.ui.control.Label
        rsUIAxes                       matlab.ui.control.UIAxes
        ReferenceTab                   matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        ListBox_3                      matlab.ui.control.ListBox
        rrxrangeSlider                 matlab.ui.control.RangeSlider
        xrangeLabel_3                  matlab.ui.control.Label
        rrUIAxes                       matlab.ui.control.UIAxes
        BackgroundsTab                 matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        ListBox_2                      matlab.ui.control.ListBox
        ListBox                        matlab.ui.control.ListBox
        rbsxrangeSlider                matlab.ui.control.RangeSlider
        Slider13Label                  matlab.ui.control.Label
        rbrxrangeSlider                matlab.ui.control.RangeSlider
        Slider12Label                  matlab.ui.control.Label
        rrbUIAxes                      matlab.ui.control.UIAxes
        rsbUIAxes                      matlab.ui.control.UIAxes
        ProcesseddataTab               matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        xrangeSlider_2                 matlab.ui.control.RangeSlider
        xrangeSlider_2Label            matlab.ui.control.Label
        SelectdatatoviewListBox        matlab.ui.control.ListBox
        SelectdatatoviewLabel_2        matlab.ui.control.Label
        p1UIAxes                       matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        xrangeSlider_3                 matlab.ui.control.RangeSlider
        xrangeSlider_3Label            matlab.ui.control.Label
        SelectdatatoviewListBox_2      matlab.ui.control.ListBox
        SelectdatatoviewLabel          matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        AdditionalcleaningTab          matlab.ui.container.Tab
        CommingsoonLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)

        ref_str               % string of characters used to indicate a file corresponds to a reference (e.g. 'quartz')
        calibration_str       % string of characters used to indicate a file corresponds to a calibration (e.g. 'polystyrene')
        vis_wl                % wavelength of the visible beam
        
        data_dir              % directory in which to look or raw data files

        sample_files          % Cell array of SampleFile objects
        bg_files              % Cell array of File objects
        ref_files             % Cell array of SignalFile objects
        calibration_files     % Cell array of SignalFile objects
        polystyrene_std       % NIST data for polystyrene imaginary refrative index

        CachedCalibration
        bkendSampleTable
        bkendRefTable
    end
    
    methods (Access = private)
        
        function UpdateUIAxes7(app)
            calibration = app.CachedCalibration;
            calibration = calibration.convert_to_wn(app.vis_wl);
    
            multiplier = app.multiplierSlider.Value;
            baseline = app.baselineSlider.Value;
            app.polystyrene_std.adj_abs = ...
                app.polystyrene_std.ImaginaryRefractiveIndex .* multiplier + baseline;
    
            cla(app.processedcalibUIAxes)
            hold(app.processedcalibUIAxes, 'on');
    
            plot(app.processedcalibUIAxes, app.polystyrene_std, "Wavenumber", "adj_abs");
            plot(app.processedcalibUIAxes, calibration.processed_data, "Wavenumber", "Absorbance");
    
            % x_vals = calibration.processed_data.Wavenumber;
            app.processedcalibUIAxes.XLim = app.xrangeSlider.Value;
    
            app.processedcalibUIAxes.XLabel.String = 'Wavenumber (cm^{-1})';
            app.processedcalibUIAxes.YLabel.String = 'Absorbance (a.u.)';
    
            legend(app.processedcalibUIAxes, 'NIST', 'Collected');
            hold(app.processedcalibUIAxes, 'off');
        end
        
        function loadState(app)
            load('StoredValues.mat', 'state')
            app.vis_wl = state.vis_wl.Value;
            app.ref_str = state.ref_str.Value;
            app.calibration_str = state.calibration_str.Value;
            app.data_dir = state.data_dir;

        end
        
        function saveState(app)
            state.data_dir = app.data_dir;
            state.vis_wl.Value = app.vis_wl;
            state.ref_str.Value = app.ref_str;
            state.calibration_str.Value = app.calibration_str;
            save('StoredValues.mat', 'state')
        end
        
        function sortFiles(app, detected_files)
            
            app.sample_files = SampleFile.empty;
            app.bg_files = File.empty;
            app.ref_files = SignalFile.empty;
            app.calibration_files = SampleFile.empty;

            for i = 1:numel(detected_files)
                fname = lower(detected_files(i).name);
                is_calibration = contains(fname, app.calibration_str);
                is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
                is_sig = ~contains(fname, "bg") || contains(fname, "bkg") || contains(fname, "background");

                if is_sig
                    if is_sample
                        f = SampleFile(detected_files(i));
                        app.sample_files(end+1) = f;
                    elseif is_calibration
                        f = SampleFile(detected_files(i));
                        app.calibration_files(end+1) = f;
                    else
                        f = SignalFile(detected_files(i));
                        app.ref_files(end+1) = f;
                    end
                else
                    f = File(detected_files(i));
                    app.bg_files(end+1) = f;
                end
            end

        end
        
        function readSampleTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.sampleUITable.Data);
            samples     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            references  = cell(nRows, 1);
            
            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                sampleName    = string(app.sampleUITable.Data{i, 1});
                backgroundName = string(app.sampleUITable.Data{i, 2});
                referenceName  = string(app.sampleUITable.Data{i, 3});
            
                % --- Match against object arrays ---
                % Find the SampleFile object
                samples{i} = app.sample_files( ...
                    find([app.sample_files.filename] == sampleName, 1) ...
                );
            
                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                );
            
                % Find the SignalFile object
                references{i} = app.ref_files( ...
                    find([app.ref_files.filename] == referenceName, 1) ...
                );
            end
            
            % Create backend table with objects
            app.bkendSampleTable = table( ...
                [samples{:}]', ...
                [backgrounds{:}]', ...
                [references{:}]', ...
                'VariableNames', {'Sample', 'Background', 'Reference'} ...
            );

        end
        
        function readRefTable(app)
            % Initialize cell arrays to hold matched objects
            nRows = height(app.refUITable.Data);
            signals     = cell(nRows, 1);
            backgrounds = cell(nRows, 1);
            
            % Loop over each row of the UI table
            for i = 1:nRows
                % --- Extract char filenames and cast to string ---
                signalName    = string(app.refUITable.Data{i, 1});
                backgroundName = string(app.refUITable.Data{i, 2});
            
                % --- Match against object arrays ---
                % Find the SignalFile object
                signals{i} = app.ref_files( ...
                    find([app.ref_files.filename] == signalName, 1) ...
                );
            
                % Find the Background File object
                backgrounds{i} = app.bg_files( ...
                    find([app.bg_files.filename] == backgroundName, 1) ...
                );
            end
            
            % Create backend table with objects
            app.bkendRefTable = table( ...
                [signals{:}]', ...
                [backgrounds{:}]', ...
                'VariableNames', {'Signal', 'Background'} ...
            );

        end
        
        function processFiles(app)
            for i = 1:height(app.bkendRefTable)
                signal = app.bkendRefTable.Signal(i);
                background = app.bkendRefTable.Background(i);
            
                % Assign background
                signal.bg_used = background;

                % subtract bg
                signal = signal.subtract_background(background);
            
                % % Update file in the table
                % app.bkendRefTable.Signal(i) = signal;
            end
            for i = 1:height(app.bkendSampleTable)
                sample = app.bkendSampleTable.Sample(i);
                background = app.bkendSampleTable.Background(i);
                reference = app.bkendSampleTable.Reference(i);
            
                % Assign the background and reference
                sample.bg_used = background;
                sample.ref_used = reference;

                % subtract bg, normalize, convert to wavenumber
                sample = sample.subtract_background(background);
                sample = sample.normalize(reference);
                sample = sample.convert_to_wn(app.vis_wl);
            
                % % Update the file in the table
                % app.bkendSampleTable.Sample(i) = sample;
            end

        end
        
        function plotRaw(app)
            
        end
        
        function plotProcessed(app)
            cla(app.p1UIAxes)

            hold(app.p1UIAxes, 'on');
            for f = app.sample_files
                plot( ...
                    app.p1UIAxes, ...
                    f.processed_data, "Wavelength", "Intensity" ...
                    )
            end
            hold(app.p1UIAxes, 'off');

            app.p1UIAxes.XLabel.String = 'Wavenumber (cm-1)';
            app.p1UIAxes.YLabel.String = 'Intensity (a.u.)';
            % legend(app.rawcalibUIAxes, char(bg.filename), char(ref.filename), char(calibration.filename));
            
            % app.processedcalibUIAxes.XLim = 
            % x_vals = app.sample_files(1).processed_data.Wavenumber;
            % app.xrangeSlider_2.Limits = [min(x_vals), max(x_vals)];
            % app.xrangeSlider_2.Value = [min(x_vals), max(x_vals)];

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('classes'));
            app.polystyrene_std = readtable( ...
                "polystyrene_imaginary_refractive_index.csv");

            try
                app.loadState;
                app.wdField.Value = string(app.data_dir);
            catch
                app.vis_wl = 793;
                app.ref_str = "zqz";
                app.calibration_str = "polystyrene";
                app.wdField.Value = "None";
            end

            app.ViswlSlider.Value = app.vis_wl;
            app.Spinner.Value = app.vis_wl;

            app.ProcesseddataTab.Parent = [];
        end

        % Value changed function: ReferencestringEditField
        function ReferencestringEditFieldValueChanged(app, event)
            app.ref_str = app.ReferencestringEditField.Value;
            
        end

        % Value changed function: CalibrationstringEditField
        function CalibrationstringEditFieldValueChanged(app, event)
            app.calibration_str = app.CalibrationstringEditField.Value;
            
        end

        % Button pushed function: SetchangeworkingdirectoryButton
        function SetchangeworkingdirectoryButtonPushed(app, event)
            app.data_dir = uigetdir();
            app.wdField.Value = string(app.data_dir);
        end

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            detected_files = dir(fullfile(app.data_dir, '*.csv'));
            app.sortFiles(detected_files)
            
            % app.sample_files = SampleFile.empty;
            % app.bg_files = File.empty;
            % app.ref_files = SignalFile.empty;
            % app.calibration_files = SampleFile.empty;
            % 
            % for i = 1:numel(detected_files)
            %     fname = lower(detected_files(i).name);
            %     is_calibration = contains(fname, app.calibration_str);
            %     is_sample = ~contains(fname, app.ref_str) && ~is_calibration;
            %     is_sig = ~contains(fname, "bg") || contains(fname, "bkg") || contains(fname, "background");
            % 
            %     if is_sig
            %         if is_sample
            %             f = SampleFile(detected_files(i));
            %             app.sample_files(end+1) = f;
            %         elseif is_calibration
            %             f = SampleFile(detected_files(i));
            %             app.calibration_files(end+1) = f;
            %         else
            %             f = SignalFile(detected_files(i));
            %             app.ref_files(end+1) = f;
            %         end
            %     else
            %         f = File(detected_files(i));
            %         app.bg_files(end+1) = f;
            %     end
            % end
            
            ref_data = cell(numel(app.ref_files), 2);
            refs = cell(1, numel(app.ref_files));
            for i = 1:numel(app.ref_files)
                ref_data{i, 1} = char(app.ref_files(i).filename);
                ref_data{i, 2} = '';
                refs{1, i} = char(app.ref_files(i).filename);
            end

            sample_data = cell(numel(app.sample_files), 3);
            for i = 1:numel(app.sample_files)
                sample_data{i, 1} = char(app.sample_files(i).filename);
                sample_data{i, 2} = '';
                sample_data{i, 3} = '';
            end

            bgs = cell(1, numel(app.bg_files));
            for i = 1:numel(app.bg_files)
                bgs{1, i} = char(app.bg_files(i).filename);
            end

            app.refUITable.ColumnName = {'Signal', 'Background'};
            app.refUITable.Data = ref_data;
            app.refUITable.ColumnFormat = {[] bgs};
            app.refUITable.ColumnEditable = true;

            app.sampleUITable.ColumnName = {'Sample', 'Background', 'Reference'};
            app.sampleUITable.Data = sample_data;
            app.sampleUITable.ColumnFormat = {[] bgs refs};
            app.sampleUITable.ColumnEditable = true;

            % Prepare dropdowns for calibration
            app.CalibrationdatafileDropDown.Items = [app.calibration_files.filename];
            app.CalibrationdatafileDropDown.ItemsData = app.calibration_files;
            
            app.ReferenceusedDropDown.Items = [app.ref_files.filename];
            app.ReferenceusedDropDown.ItemsData = app.ref_files;
            
            app.BackgroundusedDropDown.Items = [app.bg_files.filename];
            app.BackgroundusedDropDown.ItemsData = app.bg_files;


        end

        % Button pushed function: UpdateplotsButton
        function UpdateplotsButtonPushed(app, event)
            calibration = app.CalibrationdatafileDropDown.Value;
            ref =  app.ReferenceusedDropDown.Value;
            bg = app.BackgroundusedDropDown.Value;

            cla(app.rawcalibUIAxes, 'reset')

            hold(app.rawcalibUIAxes, 'on');
            plot( ...
                app.rawcalibUIAxes, ...
                bg.raw_data, "Wavelength", "Intensity" ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                ref.raw_data, "Wavelength", "Intensity" ...
                )
            plot( ...
                app.rawcalibUIAxes, ...
                calibration.raw_data, "Wavelength", "Intensity" ...
                )
            hold(app.rawcalibUIAxes, 'off');

            app.rawcalibUIAxes.XLabel.String = 'Wavelength (nm)';
            app.rawcalibUIAxes.YLabel.String = 'Intensity (cts.)';
            legend(app.rawcalibUIAxes, char(bg.filename), char(ref.filename), char(calibration.filename));

            ref = ref.subtract_background(bg);
            calibration = calibration.subtract_background(bg);
            calibration = calibration.normalize(ref);
            calibration = calibration.convert_to_wn(app.vis_wl);

            % convert from transmittance to absorbance
            calibration.processed_data.Intensity(calibration.processed_data.Intensity <= 0) = eps;
            calibration.processed_data.Absorbance = ...
                              -log10(calibration.processed_data.Intensity);
            app.CachedCalibration = calibration;


            x_vals = calibration.processed_data.Wavenumber;
            app.xrangeSlider.Limits = [min(x_vals), max(x_vals)];
            app.xrangeSlider.Value = [min(x_vals), max(x_vals)];

            cla(app.processedcalibUIAxes, 'reset')

            app.UpdateUIAxes7();

        end

        % Value changed function: ViswlSlider
        function ViswlSliderValueChanged(app, event)
            app.vis_wl = app.ViswlSlider.Value;
            app.Spinner.Value = app.vis_wl;
            app.UpdateUIAxes7();
            
        end

        % Value changed function: baselineSlider, multiplierSlider, 
        % ...and 1 other component
        function baselineSliderValueChanged(app, event)
            app.UpdateUIAxes7();
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.vis_wl = app.Spinner.Value;
            app.ViswlSlider.Value = app.vis_wl;
            app.UpdateUIAxes7();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveState;
            delete(app)
            
        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            app.readRefTable;
            app.readSampleTable;
            app.processFiles;
            app.ProcesseddataTab.Parent = app.TabGroup; % Make the tab visible

        end

        % Button down function: ProcesseddataTab
        function ProcesseddataTabButtonDown(app, event)
            app.plotProcessed;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatasortingTab = uitab(app.TabGroup);
            app.DatasortingTab.Title = 'Data sorting';
            app.GridLayout10 = uigridlayout(app.DatasortingTab);
            app.LoadandsortdatafilesPanel = uipanel(app.GridLayout10);
            app.LoadandsortdatafilesPanel.Title = 'Load and sort data files';
            app.GridLayout12 = uigridlayout(app.LoadandsortdatafilesPanel);
            app.ReferencestringEditFieldLabel = uilabel(app.GridLayout12);
            app.ReferencestringEditFieldLabel.Text = 'Reference string';
            app.ReferencestringEditField = uieditfield(app.GridLayout12, 'text');
            app.ReferencestringEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencestringEditFieldValueChanged, true);
            app.ReferencestringEditField.Value = 'zqz';
            app.CalibrationstringEditFieldLabel = uilabel(app.GridLayout12);
            app.CalibrationstringEditFieldLabel.Text = 'Calibration string';
            app.CalibrationstringEditField = uieditfield(app.GridLayout12, 'text');
            app.CalibrationstringEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationstringEditFieldValueChanged, true);
            app.CalibrationstringEditField.Value = 'polystyrene';
            app.LoadfilesButton = uibutton(app.GridLayout12, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            app.SetchangeworkingdirectoryButton = uibutton(app.GridLayout12, 'push');
            app.SetchangeworkingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetchangeworkingdirectoryButtonPushed, true);
            app.SetchangeworkingdirectoryButton.Text = 'Set/change working directory';
            app.wdField = uieditfield(app.GridLayout12, 'text');
            app.wdField.Editable = 'off';
            app.CurrentdirectoryLabel = uilabel(app.GridLayout12);
            app.CurrentdirectoryLabel.Text = 'Current directory: ';
            app.ApplychangesButton = uibutton(app.GridLayout10, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.refUITable = uitable(app.GridLayout10);
            app.refUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.refUITable.RowName = {};
            app.sampleUITable = uitable(app.GridLayout10);
            app.sampleUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.sampleUITable.RowName = {};
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.GridLayout7 = uigridlayout(app.CalibrationTab);
            app.rawcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.rawcalibUIAxes, 'Raw')
            xlabel(app.rawcalibUIAxes, 'X')
            ylabel(app.rawcalibUIAxes, 'Y')
            zlabel(app.rawcalibUIAxes, 'Z')
            app.processedcalibUIAxes = uiaxes(app.GridLayout7);
            title(app.processedcalibUIAxes, 'Processed')
            xlabel(app.processedcalibUIAxes, 'Frequency (cm-1)')
            ylabel(app.processedcalibUIAxes, 'Absorbance (a.u.)')
            zlabel(app.processedcalibUIAxes, 'Z')
            app.SetcalibrationfilesPanel = uipanel(app.GridLayout7);
            app.SetcalibrationfilesPanel.Title = 'Set calibration files';
            app.GridLayout9 = uigridlayout(app.SetcalibrationfilesPanel);
            app.CalibrationdatafileDropDownLabel = uilabel(app.GridLayout9);
            app.CalibrationdatafileDropDownLabel.Text = 'Calibration data file';
            app.CalibrationdatafileDropDown = uidropdown(app.GridLayout9);
            app.ReferenceusedLabel = uilabel(app.GridLayout9);
            app.ReferenceusedLabel.Text = 'Reference used';
            app.ReferenceusedDropDown = uidropdown(app.GridLayout9);
            app.BackgroundusedDropDownLabel = uilabel(app.GridLayout9);
            app.BackgroundusedDropDownLabel.Text = 'Background used';
            app.BackgroundusedDropDown = uidropdown(app.GridLayout9);
            app.UpdateplotsButton = uibutton(app.GridLayout9, 'push');
            app.UpdateplotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotsButtonPushed, true);
            app.UpdateplotsButton.Text = 'Update plots';
            app.CalibratevisiblewavelengthPanel = uipanel(app.GridLayout7);
            app.CalibratevisiblewavelengthPanel.Title = 'Calibrate visible wavelength';
            app.GridLayout8 = uigridlayout(app.CalibratevisiblewavelengthPanel);
            app.ViswlSlider = uislider(app.GridLayout8);
            app.ViswlSlider.Limits = [790 796];
            app.ViswlSlider.ValueChangedFcn = createCallbackFcn(app, @ViswlSliderValueChanged, true);
            app.ViswlSlider.Value = 793;
            app.Spinner = uispinner(app.GridLayout8);
            app.Spinner.Step = 0.05;
            app.Spinner.ValueDisplayFormat = '%.2f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.xrangeSliderLabel = uilabel(app.GridLayout7);
            app.xrangeSliderLabel.Text = 'x range';
            app.xrangeSlider = uislider(app.GridLayout7, 'range');
            app.xrangeSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSliderLabel = uilabel(app.GridLayout7);
            app.multiplierSliderLabel.Text = 'multiplier';
            app.multiplierSlider = uislider(app.GridLayout7);
            app.multiplierSlider.Limits = [0 20];
            app.multiplierSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.multiplierSlider.Value = 10;
            app.baselineSliderLabel = uilabel(app.GridLayout7);
            app.baselineSliderLabel.Text = 'baseline';
            app.baselineSlider = uislider(app.GridLayout7);
            app.baselineSlider.Limits = [0 3];
            app.baselineSlider.ValueChangedFcn = createCallbackFcn(app, @baselineSliderValueChanged, true);
            app.RawdataTab = uitab(app.TabGroup);
            app.RawdataTab.Title = 'Raw data';
            app.GridLayout3 = uigridlayout(app.RawdataTab);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.AllTab = uitab(app.TabGroup2);
            app.AllTab.Title = 'All';
            app.GridLayout13 = uigridlayout(app.AllTab);
            app.allrawUIAxes = uiaxes(app.GridLayout13);
            title(app.allrawUIAxes, 'Title')
            xlabel(app.allrawUIAxes, 'X')
            ylabel(app.allrawUIAxes, 'Y')
            zlabel(app.allrawUIAxes, 'Z')
            app.Tree_3 = uitree(app.GridLayout13, 'checkbox');
            app.Node_3 = uitreenode(app.Tree_3);
            app.Node_3.Text = 'Node';
            app.Node2_3 = uitreenode(app.Node_3);
            app.Node2_3.Text = 'Node2';
            app.Node3_3 = uitreenode(app.Node_3);
            app.Node3_3.Text = 'Node3';
            app.Node4_3 = uitreenode(app.Node_3);
            app.Node4_3.Text = 'Node4';
            app.xrangeLabel = uilabel(app.GridLayout13);
            app.xrangeLabel.Text = 'x range';
            app.raxrangeSlider = uislider(app.GridLayout13, 'range');
            app.SampleTab = uitab(app.TabGroup2);
            app.SampleTab.Title = 'Sample';
            app.GridLayout5 = uigridlayout(app.SampleTab);
            app.rsUIAxes = uiaxes(app.GridLayout5);
            title(app.rsUIAxes, 'Title')
            xlabel(app.rsUIAxes, 'X')
            ylabel(app.rsUIAxes, 'Y')
            zlabel(app.rsUIAxes, 'Z')
            app.xrangeLabel_2 = uilabel(app.GridLayout5);
            app.xrangeLabel_2.Text = 'x range';
            app.rsxrangeSlider = uislider(app.GridLayout5, 'range');
            app.ListBox_4 = uilistbox(app.GridLayout5);
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Item 1'};
            app.ReferenceTab = uitab(app.TabGroup2);
            app.ReferenceTab.Title = 'Reference';
            app.GridLayout4 = uigridlayout(app.ReferenceTab);
            app.rrUIAxes = uiaxes(app.GridLayout4);
            title(app.rrUIAxes, 'Title')
            xlabel(app.rrUIAxes, 'X')
            ylabel(app.rrUIAxes, 'Y')
            zlabel(app.rrUIAxes, 'Z')
            app.xrangeLabel_3 = uilabel(app.GridLayout4);
            app.xrangeLabel_3.Text = 'x range';
            app.rrxrangeSlider = uislider(app.GridLayout4, 'range');
            app.ListBox_3 = uilistbox(app.GridLayout4);
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Item 1'};
            app.BackgroundsTab = uitab(app.TabGroup2);
            app.BackgroundsTab.Title = 'Backgrounds';
            app.GridLayout6 = uigridlayout(app.BackgroundsTab);
            app.rsbUIAxes = uiaxes(app.GridLayout6);
            title(app.rsbUIAxes, 'Sample backgrounds')
            xlabel(app.rsbUIAxes, 'X')
            ylabel(app.rsbUIAxes, 'Y')
            zlabel(app.rsbUIAxes, 'Z')
            app.rrbUIAxes = uiaxes(app.GridLayout6);
            title(app.rrbUIAxes, 'Reference backgrounds')
            xlabel(app.rrbUIAxes, 'X')
            ylabel(app.rrbUIAxes, 'Y')
            zlabel(app.rrbUIAxes, 'Z')
            app.Slider12Label = uilabel(app.GridLayout6);
            app.Slider12Label.Text = 'Slider12';
            app.rbrxrangeSlider = uislider(app.GridLayout6, 'range');
            app.Slider13Label = uilabel(app.GridLayout6);
            app.Slider13Label.Text = 'Slider13';
            app.rbsxrangeSlider = uislider(app.GridLayout6, 'range');
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.ListBox_2 = uilistbox(app.GridLayout6);
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'Item 1'};
            app.ProcesseddataTab = uitab(app.TabGroup);
            app.ProcesseddataTab.Title = 'Processed data';
            app.ProcesseddataTab.ButtonDownFcn = createCallbackFcn(app, @ProcesseddataTabButtonDown, true);
            app.GridLayout = uigridlayout(app.ProcesseddataTab);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SelectdatatoviewLabel = uilabel(app.GridLayout2);
            app.SelectdatatoviewLabel.Text = 'Select data to view:';
            app.SelectdatatoviewListBox_2 = uilistbox(app.GridLayout2);
            app.SelectdatatoviewListBox_2.Multiselect = 'on';
            app.SelectdatatoviewListBox_2.Value = {'Item 1'};
            app.xrangeSlider_3Label = uilabel(app.GridLayout2);
            app.xrangeSlider_3Label.Text = 'x range';
            app.xrangeSlider_3 = uislider(app.GridLayout2, 'range');
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.p1UIAxes = uiaxes(app.GridLayout2_2);
            xlabel(app.p1UIAxes, 'X')
            ylabel(app.p1UIAxes, 'Y')
            zlabel(app.p1UIAxes, 'Z')
            app.SelectdatatoviewLabel_2 = uilabel(app.GridLayout2_2);
            app.SelectdatatoviewLabel_2.Text = 'Select data to view:';
            app.SelectdatatoviewListBox = uilistbox(app.GridLayout2_2);
            app.SelectdatatoviewListBox.Multiselect = 'on';
            app.SelectdatatoviewListBox.Value = {'Item 1'};
            app.xrangeSlider_2Label = uilabel(app.GridLayout2_2);
            app.xrangeSlider_2Label.Text = 'x range';
            app.xrangeSlider_2 = uislider(app.GridLayout2_2, 'range');
            app.AdditionalcleaningTab = uitab(app.TabGroup);
            app.AdditionalcleaningTab.Title = 'Additional cleaning';
            app.CommingsoonLabel = uilabel(app.AdditionalcleaningTab);
            app.CommingsoonLabel.Text = 'Comming soon...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end