classdef background_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Spinner_3                    matlab.ui.control.Spinner
        OffsetinterceptLabel         matlab.ui.control.Label
        Spinner_2                    matlab.ui.control.Spinner
        OffsetslopeLabel             matlab.ui.control.Label
        SmoothbackgroundnotrecommendedCheckBox  matlab.ui.control.CheckBox
        SmoothingfactorSpinner       matlab.ui.control.Spinner
        SmoothingfactorSpinnerLabel  matlab.ui.control.Label
        CancelButton                 matlab.ui.control.StateButton
        SaveButton                   matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp                   % instance of the main SFG app
        Files                     % signal files to have their background processed
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            cla(app.UIAxes)
            hold(app.UIAxes, "on")
            for i = 1:numel(app.Files)
                f = app.Files{i};
                data = app.processData(f);
                plot(app.UIAxes, ...
                    data, "Wavelength", "SignalIntensity", ...
                    DisplayName=f.filename)
                plot(app.UIAxes, ...
                    data, "Wavelength", "BgIntensity", ...
                    DisplayName=f.filename + " (background)")
            end
            hold(app.UIAxes, "off")
            app.UIAxes.XLabel.String = "Wavelength (nm)";
            app.UIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.UIAxes, Location="best", Interpreter="none")
        end
        
        function data = processData(app, file)
            file.cleanRays
            file.bg.cleanRays

            file.avg_frames
            file.bg.avg_frames

            file.bgSmoothFactor = app.SmoothingfactorSpinner.Value;
            file.bgSlope = app.Spinner_2.Value;
            file.bgIntercept = app.Spinner_3.Value;

            bgData = file.modifyBackground;

            if ~isequal(file.processed_data.Wavelength, bgData.Wavelength)
                error("Wavelength mismatch during background subtraction.")
            end
            data = table();
            data.Wavelength = bgData.Wavelength;
            data.SignalIntensity = file.processed_data.Intensity;
            data.BgIntensity = bgData.Intensity;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, files)
            movegui(app.UIFigure, "center")
            app.MainApp = mainApp;
            app.Files = files;

            % initialize widgets
            app.Spinner_2.Value = files{1}.bgSlope;
            app.Spinner_3.Value = files{1}.bgIntercept;
            app.SmoothingfactorSpinner.Value = files{1}.bgSmoothFactor;
            if files{1}.bgSmoothFactor == 0
                app.SmoothingfactorSpinner.Enable = "off";
            else
                app.SmoothingfactorSpinner.Enable = "on";
            end

            app.updatePlot

        end

        % Value changed function: SmoothbackgroundnotrecommendedCheckBox
        function SmoothbackgroundnotrecommendedCheckBoxValueChanged(app, event)
            if app.SmoothbackgroundnotrecommendedCheckBox.Value % if true
                app.SmoothingfactorSpinner.Enable = "on";
                app.updatePlot
            else
                app.SmoothingfactorSpinner.Enable = "off";
                app.SmoothingfactorSpinner.Value = 0;
                app.updatePlot
            end
            
        end

        % Value changed function: SmoothingfactorSpinner, Spinner_2, 
        % ...and 1 other component
        function SmoothingfactorSpinnerValueChanged(app, event)
            app.updatePlot
            
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            updateBgTable(app.MainApp)
            delete(app)
            
        end

        % Callback function: CancelButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.EditselectedButton.Enable = "on";
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveButton = uibutton(app.GridLayout, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            app.SmoothingfactorSpinnerLabel = uilabel(app.GridLayout);
            app.SmoothingfactorSpinnerLabel.Text = 'Smoothing factor';
            app.SmoothingfactorSpinner = uispinner(app.GridLayout);
            app.SmoothingfactorSpinner.Step = 0.05;
            app.SmoothingfactorSpinner.Limits = [0 1];
            app.SmoothingfactorSpinner.ValueDisplayFormat = '%.2f';
            app.SmoothingfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothingfactorSpinnerValueChanged, true);
            app.SmoothbackgroundnotrecommendedCheckBox = uicheckbox(app.GridLayout);
            app.SmoothbackgroundnotrecommendedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothbackgroundnotrecommendedCheckBoxValueChanged, true);
            app.SmoothbackgroundnotrecommendedCheckBox.Text = 'Smooth background (not recommended)';
            app.OffsetslopeLabel = uilabel(app.GridLayout);
            app.OffsetslopeLabel.Text = 'Offset slope';
            app.Spinner_2 = uispinner(app.GridLayout);
            app.Spinner_2.Step = 0.05;
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @SmoothingfactorSpinnerValueChanged, true);
            app.OffsetinterceptLabel = uilabel(app.GridLayout);
            app.OffsetinterceptLabel.Text = 'Offset intercept';
            app.Spinner_3 = uispinner(app.GridLayout);
            app.Spinner_3.Step = 10;
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @SmoothingfactorSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = background_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end