classdef cleaning_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        CancelButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        NocleaningCheckBox           matlab.ui.control.CheckBox
        WindowSpinner                matlab.ui.control.Spinner
        WindowSpinnerLabel           matlab.ui.control.Label
        ThresholdfactorSpinner       matlab.ui.control.Spinner
        ThresholdfactorSpinnerLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp   % instance of the calling app
        Files     % files to be cleaned
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            cla(app.UIAxes, "reset")
            hold(app.UIAxes, "on")
            for i = 1:numel(app.Files)
                f = app.Files{i};
                f.cleanRays;
                plot(app.UIAxes, ...
                    f.raw_data, "Wavelength", "CleanIntensity", ...
                    DisplayName=f.filename + " (Clean)")

                outliers = f.raw_data(f.raw_data.OutlierMask, :);
                scatter_hdl = scatter(app.UIAxes, ...
                    outliers, "Wavelength", "Intensity", ...
                    SizeData=100, MarkerEdgeColor="red", Marker="o");
                scatter_hdl.HandleVisibility = "off";
            end
            hold(app.UIAxes, "off")
            app.UIAxes.XLabel.String = "Wavelength (nm)";
            app.UIAxes.YLabel.String = "Intensity (cts.)";
            legend(app.UIAxes, Location="best", Interpreter="none")

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, files)
            movegui(app.UIFigure, "center");
            app.MainApp = mainapp;
            app.Files = files;

            app.WindowSpinner.Value = app.Files{1}.window;
            app.ThresholdfactorSpinner.Value = app.Files{1}.tf;

            app.updatePlot;
        end

        % Value changed function: WindowSpinner
        function WindowSpinnerValueChanged(app, event)
            for i = 1:numel(app.Files)
                app.Files{i}.window = app.WindowSpinner.Value;
            end
            app.updatePlot;
            
        end

        % Value changed function: ThresholdfactorSpinner
        function ThresholdfactorSpinnerValueChanged(app, event)
            for i = 1:numel(app.Files)
                app.Files{i}.tf = app.ThresholdfactorSpinner.Value;
            end
            app.updatePlot;
            
        end

        % Value changed function: NocleaningCheckBox
        function NocleaningCheckBoxValueChanged(app, event)
            if app.NocleaningCheckBox.Value
                app.ThresholdfactorSpinner.Enable = "off";
                app.WindowSpinner.Enable = "off";
                app.ThresholdfactorSpinner.Value = inf;
            else
                app.ThresholdfactorSpinner.Enable = "on";
                app.WindowSpinner.Enable = "on";
            end
            for i = 1:numel(app.Files)
                app.Files{i}.tf = app.ThresholdfactorSpinner.Value;
            end
            app.updatePlot;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateCleaningTable(app.MainApp)
            delete(app)

        end

        % Callback function: CancelButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.EditSelectedButton.Enable = "on";
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ThresholdfactorSpinnerLabel = uilabel(app.GridLayout);
            app.ThresholdfactorSpinnerLabel.Text = 'Threshold factor';
            app.ThresholdfactorSpinner = uispinner(app.GridLayout);
            app.ThresholdfactorSpinner.Step = 0.5;
            app.ThresholdfactorSpinner.ValueDisplayFormat = '%.3f';
            app.ThresholdfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdfactorSpinnerValueChanged, true);
            app.WindowSpinnerLabel = uilabel(app.GridLayout);
            app.WindowSpinnerLabel.Text = 'Window';
            app.WindowSpinner = uispinner(app.GridLayout);
            app.WindowSpinner.ValueDisplayFormat = '%.0f';
            app.WindowSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowSpinnerValueChanged, true);
            app.NocleaningCheckBox = uicheckbox(app.GridLayout);
            app.NocleaningCheckBox.ValueChangedFcn = createCallbackFcn(app, @NocleaningCheckBoxValueChanged, true);
            app.NocleaningCheckBox.Text = '   No cleaning';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cleaning_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end