classdef settings_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        IRpulsebandwidthcm1Spinner      matlab.ui.control.Spinner
        IRpulsebandwidthcm1Label        matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        UseSimonssecretoptionsCheckBox  matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp               % instance of the main SFG app
        bandwidth   double    % bandwidth of the IR pulse in wavenumber
        simon       logical   % boolean that enables simon's secret options
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, bandwidth, simon)
            movegui(app.UIFigure, "center")
            app.MainApp = MainApp;
            app.bandwidth = bandwidth;
            app.simon = simon;

            app.IRpulsebandwidthcm1Spinner.Value = app.bandwidth;
            app.UseSimonssecretoptionsCheckBox.Value = app.simon;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.bandwidth = app.IRpulsebandwidthcm1Spinner.Value;
            app.simon = app.UseSimonssecretoptionsCheckBox.Value;

            app.MainApp.bandwidth = app.bandwidth;
            app.MainApp.simon = app.simon;
            enableSettingsButton(app.MainApp)
            delete(app)

        end

        % Value changed function: IRpulsebandwidthcm1Spinner
        function IRpulsebandwidthcm1SpinnerValueChanged(app, event)
            app.bandwidth = app.IRpulsebandwidthcm1Spinner.Value;
            
        end

        % Value changed function: UseSimonssecretoptionsCheckBox
        function UseSimonssecretoptionsCheckBoxValueChanged(app, event)
            app.simon = app.UseSimonssecretoptionsCheckBox.Value;
            
        end

        % Callback function: CancelButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.AdditionalSettingsButton.Enable = "on";
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'surprise.jpg');
            app.UseSimonssecretoptionsCheckBox = uicheckbox(app.GridLayout);
            app.UseSimonssecretoptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSimonssecretoptionsCheckBoxValueChanged, true);
            app.UseSimonssecretoptionsCheckBox.Text = 'Use Simon''s secret options';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.IRpulsebandwidthcm1Label = uilabel(app.GridLayout);
            app.IRpulsebandwidthcm1Label.Text = 'IR pulse bandwidth (cm^{-1})';
            app.IRpulsebandwidthcm1Spinner = uispinner(app.GridLayout);
            app.IRpulsebandwidthcm1Spinner.Step = 25;
            app.IRpulsebandwidthcm1Spinner.ValueDisplayFormat = '%.0f';
            app.IRpulsebandwidthcm1Spinner.ValueChangedFcn = createCallbackFcn(app, @IRpulsebandwidthcm1SpinnerValueChanged, true);
            app.IRpulsebandwidthcm1Spinner.Value = 950;
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end